classdef vibra5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OverallVibrationReductionEditField  matlab.ui.control.NumericEditField
        OverallVibrationReductionLabel  matlab.ui.control.Label
        axisEditField                   matlab.ui.control.EditField
        axisEditFieldLabel              matlab.ui.control.Label
        AmplitudeNewEditField           matlab.ui.control.NumericEditField
        AmplitudeNewEditFieldLabel      matlab.ui.control.Label
        NaturalFrequencyNewHzEditField  matlab.ui.control.NumericEditField
        NaturalFrequencyNewHzEditFieldLabel  matlab.ui.control.Label
        AmplitudeOldEditField           matlab.ui.control.NumericEditField
        AmplitudeOldEditFieldLabel      matlab.ui.control.Label
        NaturalFrequencyOldHzEditField  matlab.ui.control.NumericEditField
        NaturalFrequencyOldHzEditFieldLabel  matlab.ui.control.Label
        AnalyzeButton                   matlab.ui.control.Button
        SelectedAfterDamperFileDropDown  matlab.ui.control.DropDown
        SelectedAfterDamperFileDropDownLabel  matlab.ui.control.Label
        SelectedBeforeDamperFileDropDown  matlab.ui.control.DropDown
        SelectedBeforeDamperFileDropDownLabel  matlab.ui.control.Label
        SystemComparisonLabel           matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DataAX  % Data for X-axis acceleration
        DataAY  % Data for Y-axis acceleration
        DataAZ  % Data for Z-axis acceleration
        time
        Fs
        T
        L
        t
        b
        c
        k
        D
        G
        n
        natural_frequency_y
        %P1_y
        damping_coefficient_y 
        axis
        new_DataAX
        new_DataAY
        new_DataAZ
        new_time
        new_Fs
        new_L
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = "Vibra SensePro";

            % Get a list of all CSV files in the directory
            csvFiles = dir('*.csv');
            fileNames = {csvFiles.name};

            % Set the dropdown items to these file names
            app.SelectedBeforeDamperFileDropDown.Items = fileNames;
            app.SelectedAfterDamperFileDropDown.Items = fileNames;
            %app.AxisDropDown.Items = ["AX", "AY", "AZ"];
        end

        % Value changed function: SelectedBeforeDamperFileDropDown
        function SelectedBeforeDamperFileDropDownValueChanged(app, event)
            value1= app.SelectedBeforeDamperFileDropDown.Value;
            app.b = readtable(value1, 'FileType', 'text');

        end

        % Value changed function: SelectedAfterDamperFileDropDown
        function SelectedAfterDamperFileDropDownValueChanged(app, event)
            value2 = app.SelectedAfterDamperFileDropDown.Value;
            app.c = readtable(value2, 'FileType', 'text');

            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            data = table2array(app.b);

            app.DataAX = table2array(app.b(:,"AX"));
            app.DataAY = table2array(app.b(:,"AY"));
            app.DataAZ = table2array(app.b(:,"AZ"));

            % Convert the time data to seconds
            app.time = data(:, 4)*3600 + data(:, 5)*60 + data(:, 6) + data(:, 7)/1000; % Time in seconds
        
            app.Fs = 1 / mean(diff(app.time));
            %app.T = 1/app.Fs;
            app.L = length(app.DataAX);
            %app.t = (0:app.L-1)*app.T;

            compute_spectrum = @(signal) abs(fft(signal)/app.L);
            get_single_sided = @(P2) P2(2:floor(app.L/2)+1);
            adjust_amplitude = @(P1) [2*P1(1:end-1); P1(end)];

            f = app.Fs .* (1:floor(app.L/2))/app.L;

            P2_x = compute_spectrum(app.DataAX);
            P1_x = adjust_amplitude(get_single_sided(P2_x));

            P2_y = compute_spectrum(app.DataAY);
            P1_y = adjust_amplitude(get_single_sided(P2_y));

            P2_z = compute_spectrum(app.DataAZ);
            P1_z = adjust_amplitude(get_single_sided(P2_z));


            switch app.axis
                case 'X'
                    P1_old = P1_x;
                    %axis_label = 'X-axis';
                case 'Y'
                    P1_old = P1_y;
                    %axis_label = 'Y-axis';
                case 'Z'
                    P1_old = P1_z;
                    %axis_label = 'Z-axis';
            end

            %after damping

            new_data = table2array(app.c);

            app.new_DataAX = table2array(app.c(:,"AX"));
            app.new_DataAY = table2array(app.c(:,"AY"));
            app.new_DataAZ = table2array(app.c(:,"AZ"));

            % Convert the time data to seconds
            app.new_time = new_data(:, 4)*3600 + new_data(:, 5)*60 + new_data(:, 6) + new_data(:, 7)/1000; % Time in seconds
        
            app.new_Fs = 1 / mean(diff(app.new_time));
            %app.new_T = 1/app.new_Fs;
            app.new_L = length(app.new_DataAX);
            %app.new_t = (0:app.new_L-1)*app.new_T;

            compute_spectrum = @(signal) abs(fft(signal)/app.new_L);
            get_single_sided = @(P2) P2(2:floor(app.new_L/2)+1);
            adjust_amplitude = @(P1) [2*P1(1:end-1); P1(end)];

            new_f = app.new_Fs .* (1:floor(app.new_L/2))/app.new_L;

            new_P2_x = compute_spectrum(app.new_DataAX);
            %new_P1_x = adjust_amplitude(get_single_sided(P2_x));

            new_P2_y = compute_spectrum(app.new_DataAY);
            %new_P1_y = adjust_amplitude(get_single_sided(P2_y));

            new_P2_z = compute_spectrum(app.new_DataAZ);
            %new_P1_z = adjust_amplitude(get_single_sided(P2_z));


            switch app.axis
                case 'X'
                    new_P2 = new_P2_x;
                case 'Y'
                    new_P2 = new_P2_y;                
                case 'Z'
                    new_P2 = new_P2_z;
            end

            new_P1= adjust_amplitude(get_single_sided(new_P2));

            plot(app.UIAxes, f, P1_old,'r');
            app.UIAxes.Title.String = ['Freq. Spectrum Before Damper - ' upper(app.axis) '-axis'];
            app.UIAxes.XLabel.String = 'Frequency (Hz)';
            app.UIAxes.YLabel.String = '|P1(f)|';

            plot(app.UIAxes2, new_f, new_P1)
            app.UIAxes2.Title.String = ['Freq. Spectrum After Damper - ' upper(app.axis) '-axis'];
            app.UIAxes2.XLabel.String = 'Frequency (Hz)';
            app.UIAxes2.YLabel.String = '|P1(f)|';

            % Find the natural frequency and amplitude for the old data
            [~, idx_old] = max(P1_old);
            natural_frequency_old = f(idx_old);
            amplitude_old = P1_old(idx_old);
            
            % Find the natural frequency and amplitude for the new data
            [~, idx_new] = max(new_P1);
            natural_frequency_new = new_f(idx_new);
            amplitude_new = new_P1(idx_new);

            app.NaturalFrequencyOldHzEditField.Value = natural_frequency_old;
            app.NaturalFrequencyNewHzEditField.Value = natural_frequency_new;
            app.AmplitudeOldEditField.Value = amplitude_old;
            app.AmplitudeNewEditField.Value = amplitude_new;

            improvement_percentage = ((amplitude_old - amplitude_new) / amplitude_old) * 100;
            app.OverallVibrationReductionEditField.Value = improvement_percentage;

            
        end

        % Value changed function: axisEditField
        function axisEditFieldValueChanged(app, event)
            app.axis = app.axisEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SystemComparisonLabel = uilabel(app.UIFigure);
            app.SystemComparisonLabel.Text = 'System Comparison';
            app.SelectedBeforeDamperFileDropDownLabel = uilabel(app.UIFigure);
            app.SelectedBeforeDamperFileDropDownLabel.Text = 'Selected Before Damper File';
            app.SelectedBeforeDamperFileDropDown = uidropdown(app.UIFigure);
            app.SelectedBeforeDamperFileDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedBeforeDamperFileDropDownValueChanged, true);
            app.SelectedAfterDamperFileDropDownLabel = uilabel(app.UIFigure);
            app.SelectedAfterDamperFileDropDownLabel.Text = 'Selected After Damper File';
            app.SelectedAfterDamperFileDropDown = uidropdown(app.UIFigure);
            app.SelectedAfterDamperFileDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedAfterDamperFileDropDownValueChanged, true);
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.NaturalFrequencyOldHzEditFieldLabel = uilabel(app.UIFigure);
            app.NaturalFrequencyOldHzEditFieldLabel.Text = 'Natural Frequency Old (Hz)';
            app.NaturalFrequencyOldHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeOldEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeOldEditFieldLabel.Text = 'Amplitude Old';
            app.AmplitudeOldEditField = uieditfield(app.UIFigure, 'numeric');
            app.NaturalFrequencyNewHzEditFieldLabel = uilabel(app.UIFigure);
            app.NaturalFrequencyNewHzEditFieldLabel.Text = 'Natural Frequency New (Hz)';
            app.NaturalFrequencyNewHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeNewEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeNewEditFieldLabel.Text = 'Amplitude New';
            app.AmplitudeNewEditField = uieditfield(app.UIFigure, 'numeric');
            app.axisEditFieldLabel = uilabel(app.UIFigure);
            app.axisEditFieldLabel.Text = 'axis';
            app.axisEditField = uieditfield(app.UIFigure, 'text');
            app.axisEditField.ValueChangedFcn = createCallbackFcn(app, @axisEditFieldValueChanged, true);
            app.OverallVibrationReductionLabel = uilabel(app.UIFigure);
            app.OverallVibrationReductionLabel.Text = 'Overall Vibration Reduction %';
            app.OverallVibrationReductionEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vibra5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end